classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        xSpinnerLabel  matlab.ui.control.Label
        xSpinner       matlab.ui.control.Spinner
        ySpinnerLabel  matlab.ui.control.Label
        ySpinner       matlab.ui.control.Spinner
        plotButton     matlab.ui.control.Button
        StartButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        TargetNbr % Description
        TrialNbr % Description
        covaAReel
        varAReelX
        varAReelZ
        covaReel
        varReelX
        varReelZ
        covaIntR
        PosTargetXsort
        PosTargetZsort
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            x = app.xSpinner.Value;
            y = app.ySpinner.Value;
            scatter(app.UIAxes,app.TargetNbr,app.TrialNbr)
            [Theta,covaInt] = VarianceInterpolationAllSurface(app.PosTargetXsort,app.PosTargetZsort,app.varAReelX(15:21),app.varAReelZ(15:21),app.covaAReel(15:21),x,y,'cubic',app.TrialNbr);
            if det(covaInt)>=0 & covaInt(1,1)>0 & covaInt(2,2)>0
                elpt = ellipsedata(covaInt,[x y],100,[1,2,3]);
                plot(app.UIAxes,elpt(:,1:2:end),elpt(:,2:2:end));    
            
            else 
                f = uifigure
                uialert(f,'covaInt is not a covariance matrix','attention')
            end
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 
            app.TargetNbr = 7;
            app.TrialNbr = 20;
%             
            filename = 'Rlog1et2et3.txt';
            [app.covaAReel,app.varAReelX,app.varAReelZ,app.covaReel,app.varReelX,app.varReelZ,app.covaIntR,app.PosTargetXsort,app.PosTargetZsort] =process(filename,app.TrialNbr,app.TargetNbr,1,1,'Reyehanneh');           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualisation of the estimated two dimensionnal variance in every position of the table ')
            xlabel(app.UIAxes, 'x position (m)')
            ylabel(app.UIAxes, 'y position (m)')
            app.UIAxes.XLim = [-0.15 0.8];
            app.UIAxes.YLim = [-0.15 0.8];
            app.xSpinnerLabel = uilabel(app.UIFigure);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.UIFigure);
            app.xSpinner.Value = 0.2;
            app.ySpinnerLabel = uilabel(app.UIFigure);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.UIFigure);
            app.ySpinner.Value = 0.25;
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end