classdef appvarianceCombined < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        XproprioceptionSpinnerLabel  matlab.ui.control.Label
        XproprioceptionSpinner       matlab.ui.control.Spinner
        YproprioceptionSpinnerLabel  matlab.ui.control.Label
        YproprioceptionSpinner       matlab.ui.control.Spinner
        plotButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        XvisionSpinnerLabel          matlab.ui.control.Label
        XvisionSpinner               matlab.ui.control.Spinner
        YvisionSpinnerLabel          matlab.ui.control.Label
        YvisionSpinner               matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        TargetNbr % Description
        TrialNbr % Description
        covaAReel
        varAReelX
        varAReelZ
        covaReel
        varReelX
        varReelZ
        covaIntR
        PosTargetXsort
        PosTargetZsort
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.UIAxes.cla
            Xproprio = app.XproprioceptionSpinner.Value;
            Yproprio = app.YproprioceptionSpinner.Value;
            Xvisu = app.XvisionSpinner.Value;
            Yvisu = app.YvisionSpinner.Value;
            scatter(app.UIAxes,app.TargetNbr,app.TrialNbr)
            [ThetaVisu,covaIntVisu] = VarianceInterpolationAllSurface(app.PosTargetXsort,app.PosTargetZsort,app.varSommeX(15:21),app.varSommeZ(15:21),app.covaSomme(15:21),Xvisu,Yvisu,'cubic',app.TrialNbr);
            [ThetaProprio,covaIntProprio] = VarianceInterpolationAllSurface(app.PosTargetXsort,app.PosTargetZsort,app.varSommeX(1:7),app.varSommeZ(1:7),app.covaSomme(1:7),Xproprio,Yproprio,'cubic',app.TrialNbr);
            [NewCova,NewCenter] = CalculEllipseCombine(Xvisu,Yvisu,Xproprio,Yproprio,covaIntVisu,covaIntProprio)
            if det(covaIntVisu)>=0 & covaIntVisu(1,1)>0 & covaIntVisu(2,2)>0
                elpt = ellipsedata(covaIntVisu,[Xvisu Yvisu],100,[1,2,3]);
            else
                f = uifigure
                uialert(f,'covaVisu is not a covariance matrix','attention')
            end
            
            if det(covaIntProprio)>=0 & covaIntProprio(1,1)>0 & covaIntProprio(2,2)>0
                elpt1 = ellipsedata(covaIntProprio,[Xproprio Yproprio],100,[1,2,3]);
            else
                f = uifigure
                uialert(f,'covaProprio is not a covariance matrix','attention')
            end
            
            if det(NewCova)>=0 & NewCova(1,1)>0 & NewCova(2,2)>0
                elpt2 = ellipsedata(NewCova,NewCenter,100,[1,2,3]);
    
            else
                f = uifigure
                uialert(f,'covaCombine is not a covariance matrix','attention')
            end

            plot(app.UIAxes,elpt(:,1:2:end),elpt(:,2:2:end));
            hold(app.UIAxes,'on')
            plot(app.UIAxes,elpt1(:,1:2:end),elpt1(:,2:2:end));
            hold(app.UIAxes,'on')
            plot(app.UIAxes,elpt2(:,1:2:end),elpt2(:,2:2:end),'--');
            hold(app.UIAxes,'off')
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 
            app.TargetNbr = 7;
            app.TrialNbr = 20;
%             
            filename = 'Rlog1et2et3.txt';
            [app.covaAReel,app.varAReelX,app.varAReelZ,app.covaReel,app.varReelX,app.varReelZ,app.covaIntR,app.PosTargetXsort,app.PosTargetZsort] =process(filename,app.TrialNbr,app.TargetNbr,1,1,'Reyehanneh');           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Visualisation of the estimated two dimensionnal variance in every position of the table ')
            xlabel(app.UIAxes, 'x position (m)')
            ylabel(app.UIAxes, 'y position (m)')
            app.UIAxes.XLim = [-0.15 0.8];
            app.UIAxes.YLim = [-0.15 0.8];
            app.XproprioceptionSpinnerLabel = uilabel(app.UIFigure);
            app.XproprioceptionSpinnerLabel.Text = 'Xproprioception';
            app.XproprioceptionSpinner = uispinner(app.UIFigure);
            app.XproprioceptionSpinner.Value = 0.2;
            app.YproprioceptionSpinnerLabel = uilabel(app.UIFigure);
            app.YproprioceptionSpinnerLabel.Text = 'Yproprioception';
            app.YproprioceptionSpinner = uispinner(app.UIFigure);
            app.YproprioceptionSpinner.Value = 0.25;
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.XvisionSpinnerLabel = uilabel(app.UIFigure);
            app.XvisionSpinnerLabel.Text = 'Xvision';
            app.XvisionSpinner = uispinner(app.UIFigure);
            app.XvisionSpinner.Value = 0.2;
            app.YvisionSpinnerLabel = uilabel(app.UIFigure);
            app.YvisionSpinnerLabel.Text = 'Yvision';
            app.YvisionSpinner = uispinner(app.UIFigure);
            app.YvisionSpinner.Value = 0.25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appvarianceCombined

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end